classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        RenderButton            matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        ResolutionPanel         matlab.ui.container.Panel
        CreatePathTracerButton  matlab.ui.control.Button
        xEditFieldLabel         matlab.ui.control.Label
        xEditField              matlab.ui.control.NumericEditField
        yEditFieldLabel         matlab.ui.control.Label
        yEditField              matlab.ui.control.NumericEditField
        SaveimageButton         matlab.ui.control.Button
    end


    properties (Access = private)
        mPathTracer@PathTracer % Description
        mSetupComplete@logical = false
    end


    methods (Access = private)

        % Button pushed function: RenderButton
        function RenderButtonPushed(app, event)
            if (app.mSetupComplete)
                app.mPathTracer.render(app);
            else
                error("Setup is not complete!");
            end
        end

        % Button pushed function: CreatePathTracerButton
        function CreatePathTracerButtonPushed(app, event)
            x = app.xEditField.Value;
            y = app.yEditField.Value;
            app.mPathTracer = PathTracer(x,y);
            app.mSetupComplete = true;
            app.CreatePathTracerButton.BackgroundColor = [0 1 0];
        end

        % Button pushed function: SaveimageButton
        function SaveimageButtonPushed(app, event)
            if (app.mSetupComplete)
                app.mPathTracer.writeOutputToFile();
            else
                error("Setup is not complete!");
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RenderButton = uibutton(app.UIFigure, 'push');
            app.RenderButton.ButtonPushedFcn = createCallbackFcn(app, @RenderButtonPushed, true);
            app.RenderButton.Text = 'Render';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Output')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ResolutionPanel = uipanel(app.UIFigure);
            app.ResolutionPanel.Title = 'Resolution';
            app.CreatePathTracerButton = uibutton(app.ResolutionPanel, 'push');
            app.CreatePathTracerButton.ButtonPushedFcn = createCallbackFcn(app, @CreatePathTracerButtonPushed, true);
            app.CreatePathTracerButton.Text = 'Create PathTracer';
            app.xEditFieldLabel = uilabel(app.ResolutionPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.ResolutionPanel, 'numeric');
            app.xEditField.UpperLimitInclusive = 'off';
            app.xEditField.Limits = [1 Inf];
            app.xEditField.RoundFractionalValues = 'on';
            app.xEditField.ValueDisplayFormat = '%.0f';
            app.xEditField.Value = 1;
            app.yEditFieldLabel = uilabel(app.ResolutionPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.ResolutionPanel, 'numeric');
            app.yEditField.UpperLimitInclusive = 'off';
            app.yEditField.Limits = [1 Inf];
            app.yEditField.RoundFractionalValues = 'on';
            app.yEditField.ValueDisplayFormat = '%.0f';
            app.yEditField.Value = 1;
            app.SaveimageButton = uibutton(app.UIFigure, 'push');
            app.SaveimageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveimageButtonPushed, true);
            app.SaveimageButton.Text = 'Save image';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end